classdef BladeDesignerBeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BladeDesigner                  matlab.ui.Figure
        BladeDesignerBetaVersionLabel  matlab.ui.control.Label
        Selection                      matlab.ui.container.TabGroup
        Turbine                        matlab.ui.container.Tab
        CamberAxes                     matlab.ui.control.UIAxes
        ThicknessAxes                  matlab.ui.control.UIAxes
        BladeAxes                      matlab.ui.control.UIAxes
        InletMetalAngleSlider          matlab.ui.control.Slider
        OutletMetalAngleSliderLabel    matlab.ui.control.Label
        OutletMetalAngleSlider         matlab.ui.control.Slider
        InletMetalAngleSliderLabel     matlab.ui.control.Label
        StaggerAngleSlider             matlab.ui.control.Slider
        StaggerAngleSliderLabel        matlab.ui.control.Label
        ChoosebladethicknessprofileLabel  matlab.ui.control.Label
        LogdetailsLabel                matlab.ui.control.Label
        ThicknessOptions               matlab.ui.control.ListBox
        InletMetalAngleValue           matlab.ui.control.EditField
        OutletMetalAngleValue          matlab.ui.control.EditField
        StaggerAngleValue              matlab.ui.control.EditField
        AddthickprofileButton          matlab.ui.control.Button
        CamberLineAnglesLabel          matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Camber1Value                   matlab.ui.control.EditField
        Label_3                        matlab.ui.control.Label
        Camber2Value                   matlab.ui.control.EditField
        Label_2                        matlab.ui.control.Label
        CamberAngleValue               matlab.ui.control.EditField
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        DeleteprofileButton            matlab.ui.control.Button
        IncreaseordecreasethemaximumthicknessLabel  matlab.ui.control.Label
        MaximumThicknessoftheProfileLabel  matlab.ui.control.Label
        MaximumThicknessProfile        matlab.ui.control.NumericEditField
        MaxThicknessSlider             matlab.ui.control.Slider
        MaxThicknessValue              matlab.ui.control.NumericEditField
        MaxThicknessSliderValue        matlab.ui.control.EditField
        Log                            matlab.ui.control.TextArea
        CompressorTab                  matlab.ui.container.Tab
        ManualTab                      matlab.ui.container.Tab
        LeadingEdgeParametersLabel     matlab.ui.control.Label
        SemimajoraxisSliderLabel       matlab.ui.control.Label
        SemimajoraxisSlider            matlab.ui.control.Slider
        SemiminoraxisSliderLabel       matlab.ui.control.Label
        SemiminoraxisSlider            matlab.ui.control.Slider
        InletMetalSliderLabel          matlab.ui.control.Label
        InletMetalSlider               matlab.ui.control.Slider
        Angle1SliderLabel              matlab.ui.control.Label
        Angle1Slider                   matlab.ui.control.Slider
        Angle2SliderLabel              matlab.ui.control.Label
        Angle2Slider                   matlab.ui.control.Slider
    end


    properties (Access = public)
            FileName = CreateLogFile();
    end

    methods (Access = private)
        
        function plotting(app)
            % Save angles
            InletMetalAngle = app.InletMetalAngleSlider.Value;
            OutletMetalAngle = app.OutletMetalAngleSlider.Value;
            Stagger = app.StaggerAngleSlider.Value;
            
            InletCamberAngle = InletMetalAngle + Stagger;
            OutletCamberAngle = OutletMetalAngle - Stagger;
            
            
            % Obtain Camber Line
            Camber = CamberLine(InletCamberAngle,OutletCamberAngle,Stagger);

            % Obtain extra plots
            Extras = PlottingExtras(InletCamberAngle, OutletCamberAngle, Stagger, Camber(4,1000));
            Curve = Extras{2};
            Line1 = Extras{1};
            Line2 = Extras{3};
            Line3 = Extras{4};
            Curve2 = Extras{5};
            Curve3 = Extras{6};
            
            % Emergency messages - check them
%             if InletCamberAngle - Stagger < 0
%                message = 'Inlet Camber Angle is significantly small and/or Stagger is significantly large. Please, consider changing these values!';
%                uialert(app.BladeDesigner,message,'Recommendation','Icon','info');
%             end
            
%             if OutletCamberAngle + Stagger >= 90
%                message = 'Outlet Camber Angle and/or is significantly large. Please, consider changing these values!';
%                uialert(app.BladeDesigner,message,'Recommendation','Icon','info');
%             end
            
            % Obtain plots
                % Camber Line
            plot(app.CamberAxes,Camber(3,:),Camber(4,:))
            hold(app.CamberAxes,'on')
%              pan(app.CamberAxes,'on');
%             zoom(app.CamberAxes,'on');
                % Inlet Metal Arc
            plot(app.CamberAxes,Curve(1,:),Curve(2,:))
            hold(app.CamberAxes,'on')
                % Outlet Metal Arc
            plot(app.CamberAxes,Curve2(1,:),Curve2(2,:))
            hold(app.CamberAxes,'on')
                % Tangent line at inlet
            plot(app.CamberAxes,Line1(1,:),Line1(2,:))
            hold(app.CamberAxes,'on')
                % Horizontal line at outlet
            plot(app.CamberAxes,Line2(1,:),Line2(2,:))
            hold(app.CamberAxes,'on')
                % Tangent line at outlet
            plot(app.CamberAxes,Line3(1,:),Line3(2,:))
            hold(app.CamberAxes,'on')
                % Camber Angle Arc
            plot(app.CamberAxes,Curve3(1,:),Curve3(2,:))
            hold(app.CamberAxes,'off')
                % Print angles symbols
            t1 = text(app.CamberAxes,Curve(1,500)-0.03,Curve(2,500)-0.03,'\chi_{1}','HorizontalAlignment','left' );
            t2 = text(app.CamberAxes,Curve2(1,500)+0.03,Curve2(2,500)-0.03,'\chi_{2}','HorizontalAlignment','left' );
            t3 = text(app.CamberAxes,Curve3(1,1000)+0.03,Curve3(2,1000)-0.03,'\theta','HorizontalAlignment','left' );
            a1 = 14;
            a3 = 'bold';
            a4 = 'red';
            t1.FontSize = a1;
            t1.FontWeight = a3;
            t1.Color = a4;
            t2.FontSize = a1;
            t2.FontWeight = a3;
            t2.Color = a4;
            t3.FontSize = a1;
            t3.FontWeight = a3;
            t3.Color = a4;
        end
        
        
        function plotting2(app)
            % Load Angles
            InletMetalAngle = app.InletMetalAngleSlider.Value;
            OutletMetalAngle = app.OutletMetalAngleSlider.Value;
            Stagger = app.StaggerAngleSlider.Value;
            
            % Get Camber and Blade Profiles
            Camber = CamberLine(InletMetalAngle+Stagger,OutletMetalAngle-Stagger,Stagger);
            Blade = BladeProfile(Camber(1,:), Camber(2,:), app.ThicknessOptions.Value, Stagger, app.MaxThicknessValue.Value);
            
            % Plot
            plot(app.BladeAxes,Blade{1,:},Blade{2,:})
            hold(app.BladeAxes,'on')
            plot(app.BladeAxes,Camber(3,:),Camber(4,:))
            hold(app.BladeAxes,'off')
%             pan(app.BladeAxes,'on');
%             zoom(app.BladeAxes,'on');
            plot(app.ThicknessAxes,Blade{3,:},Blade{4,:})
%             pan(app.ThicknessAxes,'on');
%             zoom(app.ThicknessAxes,'on');
        end
        
        function values_angles(app)
            Metal1 = app.InletMetalAngleSlider.Value;
            Metal2 = app.OutletMetalAngleSlider.Value;
            Stagger = app.StaggerAngleSlider.Value;
            
            Camber1 = Metal1 + Stagger;
            Camber2 = Metal2 - Stagger;
            
            app.Camber1Value.Value = strcat(num2str(Camber1),char(176));
            
            app.Camber2Value.Value = strcat(num2str(Camber2),char(176));
            
            CamberAngle = Metal1+Metal2;
            app.CamberAngleValue.Value = strcat(num2str(CamberAngle),char(176));
        end
        
        function thickness(app)
            Options = importdata('ThicknessProfilesTurbine.dat');
            app.ThicknessOptions.Items = Options;
            thickness2(app)
        end
            
        function thickness2(app)
            Max_T = MaxThickness(app.ThicknessOptions.Value);
            app.MaximumThicknessProfile.Value = Max_T(2);
            if app.MaxThicknessSlider.Value<0
               text = sprintf('%5.2f %%',app.MaxThicknessSlider.Value);
            elseif app.MaxThicknessSlider.Value>0
               text = sprintf('%5.2f %%',app.MaxThicknessSlider.Value);
            else
                text = '0%';
            end
            app.MaxThicknessSliderValue.Value = text;
            app.MaxThicknessValue.Value = (1+app.MaxThicknessSlider.Value/100)*Max_T(2)/100;
            plotting(app);
            plotting2(app);
        end
        
        function LogUpload(app)
           fileID = fopen(app.FileName);
           tline = fgetl(fileID);
           i = 1;
           Value{i} = sprintf('%s\n',tline);
           while ischar(tline)
              tline = fgetl(fileID);
              if tline ==-1
                  break;
              else
                  i=i+1;
                  Value{i} = sprintf('%s\n',tline);
              end
           end
           fclose(fileID);
           app.Log.Value = Value;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Options = importdata('ThicknessProfilesTurbine.dat');
            app.ThicknessOptions.Items = Options;
            app.ThicknessOptions.Value = app.ThicknessOptions.Items{1};
            thickness2(app);
        end

        % Button pushed function: AddthickprofileButton
        function AddthickprofileButtonPushed(app, event)
            run('ThicknessProfile.mlapp');
        end

        % Button pushed function: DeleteprofileButton
        function DeleteprofileButtonPushed(app, event)
            type = 'Turbine';
            ProfileName = app.ThicknessOptions.Value;
            Message = DeleteProfile(ProfileName,type);
            uialert(app.BladeDesigner,Message,'File uploaded','Icon','../Images/ok.png')
            thickness(app)
        end

        % Value changed function: InletMetalAngleSlider
        function InletMetalAngleSliderValueChanged(app, event)
            InletMetalAngle = app.InletMetalAngleSlider.Value;
            app.InletMetalAngleValue.Value = strcat(num2str(InletMetalAngle), char(176));
            Text = ['Inlet Metal Angle has been changed to ',app.InletMetalAngleValue.Value];
            LogFile(app.FileName, Text);
            LogUpload(app);   
            plotting(app)
            plotting2(app)
            values_angles(app)
            thickness(app)
        end

        % Callback function
        function LogValueChanged(app, event)
            
        end

        % Value changed function: MaxThicknessSlider
        function MaxThicknessSliderValueChanged(app, event)
            thickness(app)
            Text = ['Maximum Thickness has been changed to ',num2str(app.MaxThicknessValue.Value)];
            LogFile(app.FileName, Text);
            LogUpload(app);   
        end

        % Value changed function: MaxThicknessSliderValue
        function MaxThicknessSliderValueValueChanged(app, event)
            thickness(app)
        end

        % Value changed function: OutletMetalAngleSlider
        function OutletMetalAngleSliderValueChanged(app, event)
            OutletMetalAngle = app.OutletMetalAngleSlider.Value;
            app.OutletMetalAngleValue.Value = strcat(num2str(OutletMetalAngle), char(176));
            Text = ['Outlet Metal Angle has been changed to ',app.OutletMetalAngleValue.Value];
            LogFile(app.FileName, Text);
            LogUpload(app);
            plotting(app)
            plotting2(app)
            values_angles(app)
            thickness(app)
        end

        % Value changed function: StaggerAngleSlider
        function StaggerAngleSliderValueChanged(app, event)
            Stagger = app.StaggerAngleSlider.Value;
            app.StaggerAngleValue.Value = strcat(num2str(Stagger), char(176));
            Text = ['Stagger Angle has been changed to ',app.StaggerAngleValue.Value];
            LogFile(app.FileName, Text);
            LogUpload(app);
            plotting(app)
            plotting2(app)
            values_angles(app)
            thickness(app)
        end

        % Value changed function: ThicknessOptions
        function ThicknessOptionsValueChanged(app, event)
            plotting(app)
            plotting2(app)
            thickness(app)
            Text = ['Thickness Profile has been changed to ',app.ThicknessOptions.Value];
            LogFile(app.FileName, Text);
            LogUpload(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BladeDesigner = uifigure;
            app.BladeDesigner.Name = 'UI Figure';
            app.BladeDesignerBetaVersionLabel = uilabel(app.BladeDesigner);
            app.BladeDesignerBetaVersionLabel.Text = 'Blade Designer Beta Version';
            app.Selection = uitabgroup(app.BladeDesigner);
            app.Turbine = uitab(app.Selection);
            app.Turbine.Title = 'Turbine';
            app.CamberAxes = uiaxes(app.Turbine);
            title(app.CamberAxes, 'Camber Line')
            xlabel(app.CamberAxes, 'X')
            ylabel(app.CamberAxes, 'Y')
            app.CamberAxes.XAxisLocation = 'origin';
            app.CamberAxes.YAxisLocation = 'origin';
            app.ThicknessAxes = uiaxes(app.Turbine);
            title(app.ThicknessAxes, 'Thickness Profile')
            xlabel(app.ThicknessAxes, 'X')
            ylabel(app.ThicknessAxes, 'Y')
            app.ThicknessAxes.XAxisLocation = 'origin';
            app.ThicknessAxes.YAxisLocation = 'origin';
            app.BladeAxes = uiaxes(app.Turbine);
            title(app.BladeAxes, 'Blade Profile')
            xlabel(app.BladeAxes, 'X')
            ylabel(app.BladeAxes, 'Y')
            app.BladeAxes.XAxisLocation = 'origin';
            app.BladeAxes.YAxisLocation = 'origin';
            app.InletMetalAngleSlider = uislider(app.Turbine);
            app.InletMetalAngleSlider.Limits = [0 90];
            app.InletMetalAngleSlider.ValueChangedFcn = createCallbackFcn(app, @InletMetalAngleSliderValueChanged, true);
            app.OutletMetalAngleSliderLabel = uilabel(app.Turbine);
            app.OutletMetalAngleSliderLabel.Text = 'Outlet Metal Angle';
            app.OutletMetalAngleSlider = uislider(app.Turbine);
            app.OutletMetalAngleSlider.Limits = [0 90];
            app.OutletMetalAngleSlider.ValueChangedFcn = createCallbackFcn(app, @OutletMetalAngleSliderValueChanged, true);
            app.InletMetalAngleSliderLabel = uilabel(app.Turbine);
            app.InletMetalAngleSliderLabel.Text = 'Inlet Metal Angle';
            app.StaggerAngleSlider = uislider(app.Turbine);
            app.StaggerAngleSlider.Limits = [0 90];
            app.StaggerAngleSlider.ValueChangedFcn = createCallbackFcn(app, @StaggerAngleSliderValueChanged, true);
            app.StaggerAngleSliderLabel = uilabel(app.Turbine);
            app.StaggerAngleSliderLabel.Text = 'Stagger Angle';
            app.ChoosebladethicknessprofileLabel = uilabel(app.Turbine);
            app.ChoosebladethicknessprofileLabel.Text = 'Choose blade thickness profile';
            app.LogdetailsLabel = uilabel(app.Turbine);
            app.LogdetailsLabel.Text = 'Log details';
            app.ThicknessOptions = uilistbox(app.Turbine);
            app.ThicknessOptions.Items = {};
            app.ThicknessOptions.ValueChangedFcn = createCallbackFcn(app, @ThicknessOptionsValueChanged, true);
            app.ThicknessOptions.Value = {};
            app.InletMetalAngleValue = uieditfield(app.Turbine, 'text');
            app.InletMetalAngleValue.Editable = 'off';
            app.OutletMetalAngleValue = uieditfield(app.Turbine, 'text');
            app.OutletMetalAngleValue.Editable = 'off';
            app.StaggerAngleValue = uieditfield(app.Turbine, 'text');
            app.StaggerAngleValue.Editable = 'off';
            app.AddthickprofileButton = uibutton(app.Turbine, 'push');
            app.AddthickprofileButton.ButtonPushedFcn = createCallbackFcn(app, @AddthickprofileButtonPushed, true);
            app.AddthickprofileButton.Text = 'Add thick profile';
            app.CamberLineAnglesLabel = uilabel(app.Turbine);
            app.CamberLineAnglesLabel.Text = 'Camber Line Angles';
            app.Label = uilabel(app.Turbine);
            app.Label.Text = 'θ';
            app.Camber1Value = uieditfield(app.Turbine, 'text');
            app.Camber1Value.Editable = 'off';
            app.Label_3 = uilabel(app.Turbine);
            app.Label_3.Text = 'θ';
            app.Camber2Value = uieditfield(app.Turbine, 'text');
            app.Camber2Value.Editable = 'off';
            app.Label_2 = uilabel(app.Turbine);
            app.Label_2.Text = 'θ';
            app.CamberAngleValue = uieditfield(app.Turbine, 'text');
            app.CamberAngleValue.Editable = 'off';
            app.Label_4 = uilabel(app.Turbine);
            app.Label_4.Text = '1';
            app.Label_5 = uilabel(app.Turbine);
            app.Label_5.Text = '2';
            app.DeleteprofileButton = uibutton(app.Turbine, 'push');
            app.DeleteprofileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteprofileButtonPushed, true);
            app.DeleteprofileButton.Text = 'Delete profile';
            app.IncreaseordecreasethemaximumthicknessLabel = uilabel(app.Turbine);
            app.IncreaseordecreasethemaximumthicknessLabel.Text = 'Increase or decrease the maximum thickness';
            app.MaximumThicknessoftheProfileLabel = uilabel(app.Turbine);
            app.MaximumThicknessoftheProfileLabel.Text = 'Maximum Thickness of the Profile (%)';
            app.MaximumThicknessProfile = uieditfield(app.Turbine, 'numeric');
            app.MaximumThicknessProfile.Editable = 'off';
            app.MaxThicknessSlider = uislider(app.Turbine);
            app.MaxThicknessSlider.Limits = [-100 100];
            app.MaxThicknessSlider.ValueChangedFcn = createCallbackFcn(app, @MaxThicknessSliderValueChanged, true);
            app.MaxThicknessValue = uieditfield(app.Turbine, 'numeric');
            app.MaxThicknessValue.ValueDisplayFormat = '%.3f';
            app.MaxThicknessValue.Editable = 'off';
            app.MaxThicknessSliderValue = uieditfield(app.Turbine, 'text');
            app.MaxThicknessSliderValue.ValueChangedFcn = createCallbackFcn(app, @MaxThicknessSliderValueValueChanged, true);
            app.MaxThicknessSliderValue.Editable = 'off';
            app.Log = uitextarea(app.Turbine);
            app.Log.Editable = 'off';
            app.Log.Value = {''; ''};
            app.CompressorTab = uitab(app.Selection);
            app.CompressorTab.Title = 'Compressor';
            app.ManualTab = uitab(app.Selection);
            app.ManualTab.Title = 'Manual';
            app.LeadingEdgeParametersLabel = uilabel(app.ManualTab);
            app.LeadingEdgeParametersLabel.Text = 'Leading Edge Parameters';
            app.SemimajoraxisSliderLabel = uilabel(app.ManualTab);
            app.SemimajoraxisSliderLabel.Text = 'Semimajor axis';
            app.SemimajoraxisSlider = uislider(app.ManualTab);
            app.SemimajoraxisSlider.Limits = [0 0.1];
            app.SemiminoraxisSliderLabel = uilabel(app.ManualTab);
            app.SemiminoraxisSliderLabel.Text = 'Semiminor axis';
            app.SemiminoraxisSlider = uislider(app.ManualTab);
            app.SemiminoraxisSlider.Limits = [0 0.1];
            app.InletMetalSliderLabel = uilabel(app.ManualTab);
            app.InletMetalSliderLabel.Text = 'Inlet Metal';
            app.InletMetalSlider = uislider(app.ManualTab);
            app.InletMetalSlider.Limits = [0 90];
            app.Angle1SliderLabel = uilabel(app.ManualTab);
            app.Angle1SliderLabel.Text = 'Angle 1';
            app.Angle1Slider = uislider(app.ManualTab);
            app.Angle1Slider.Limits = [0 90];
            app.Angle2SliderLabel = uilabel(app.ManualTab);
            app.Angle2SliderLabel.Text = 'Angle 2';
            app.Angle2Slider = uislider(app.ManualTab);
            app.Angle2Slider.Limits = [0 90];
        end
    end

    methods (Access = public)

        % Construct app
        function app = BladeDesignerBeta

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BladeDesigner)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BladeDesigner)
        end
    end
end